classdef BBA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BBA_Loading_DemoUIFigure     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ParametersTab                matlab.ui.container.Tab
        SpecifynumberofdemosLabel    matlab.ui.control.Label
        Spinner                      matlab.ui.control.Spinner
        DoShuaiWeiPingpuSwitchLabel  matlab.ui.control.Label
        DoShuaiWeiPingpuSwitch       matlab.ui.control.Switch
        ShowkeyproessSwitchLabel     matlab.ui.control.Label
        ShowkeyproessSwitch          matlab.ui.control.Switch
        SimulateButton               matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            nDemo = app.Spinner.Value;
            if strcmp(app.DoShuaiWeiPingpuSwitch.Value,'On')
                ISshuaiwei = 1;
            else
                ISshuaiwei = 0;
            end
            if strcmp(app.ShowkeyproessSwitch.Value,'On')
                ISshowProess = 1;
            else
                ISshowProess = 0;
            end
            
            run demo(nDemo,ISshuaiwei,ISshowProess)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BBA_Loading_DemoUIFigure = uifigure;
            app.BBA_Loading_DemoUIFigure.Name = 'BBA_Loading_Demo';
            app.TabGroup = uitabgroup(app.BBA_Loading_DemoUIFigure);
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.SpecifynumberofdemosLabel = uilabel(app.ParametersTab);
            app.SpecifynumberofdemosLabel.Text = 'Specify number of demos ';
            app.Spinner = uispinner(app.ParametersTab);
            app.Spinner.Limits = [1 9];
            app.Spinner.Editable = 'off';
            app.Spinner.Value = 1;
            app.DoShuaiWeiPingpuSwitchLabel = uilabel(app.ParametersTab);
            app.DoShuaiWeiPingpuSwitchLabel.Text = 'Do ShuaiWei Pingpu';
            app.DoShuaiWeiPingpuSwitch = uiswitch(app.ParametersTab, 'slider');
            app.DoShuaiWeiPingpuSwitch.Value = 'On';
            app.ShowkeyproessSwitchLabel = uilabel(app.ParametersTab);
            app.ShowkeyproessSwitchLabel.Text = 'Show key proess';
            app.ShowkeyproessSwitch = uiswitch(app.ParametersTab, 'slider');
            app.SimulateButton = uibutton(app.BBA_Loading_DemoUIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
        end
    end

    methods (Access = public)

        % Construct app
        function app = BBA

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BBA_Loading_DemoUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BBA_Loading_DemoUIFigure)
        end
    end
end